classdef VL_LickSort < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Toolbar                        matlab.ui.container.Toolbar
        ResetTool                      matlab.ui.container.toolbar.PushTool
        QATool                         matlab.ui.container.toolbar.PushTool
        AnalyzeTool                    matlab.ui.container.toolbar.PushTool
        SaveTool                       matlab.ui.container.toolbar.PushTool
        PlotTool                       matlab.ui.container.toolbar.PushTool
        CompletedTool                  matlab.ui.container.toolbar.PushTool
        ShowLegendCheckBox             matlab.ui.control.CheckBox
        DropDown                       matlab.ui.control.DropDown
        BrowseframesmodifiedbyQALabel  matlab.ui.control.Label
        FilterbyRewardTypeLabel        matlab.ui.control.Label
        RewardedCheckBox               matlab.ui.control.CheckBox
        NotrewardedCheckBox            matlab.ui.control.CheckBox
        ShowHarvestCheckBox            matlab.ui.control.CheckBox
        GroomingCheckBox               matlab.ui.control.CheckBox
        OuterCheckBox                  matlab.ui.control.CheckBox
        UnderCheckBox                  matlab.ui.control.CheckBox
        InnerCheckBox                  matlab.ui.control.CheckBox
        FilterbyLickTypeLabel          matlab.ui.control.Label
        MaxRetractionSpeedLabel        matlab.ui.control.Label
        QueryByFrameWarningLabel       matlab.ui.control.Label
        FileTitleLabel                 matlab.ui.control.Label
        FileLabel                      matlab.ui.control.Label
        StatusLabel                    matlab.ui.control.Label
        StatusTitleLabel               matlab.ui.control.Label
        UndertubeCheckBox              matlab.ui.control.CheckBox
        OuteredgeCheckBox              matlab.ui.control.CheckBox
        InnertubeCheckBox              matlab.ui.control.CheckBox
        NorewardCheckBox               matlab.ui.control.CheckBox
        RewardCheckBox                 matlab.ui.control.CheckBox
        RightCheckBox                  matlab.ui.control.CheckBox
        LickTypeLabel                  matlab.ui.control.Label
        LeftCheckBox                   matlab.ui.control.CheckBox
        MaxLickLabel                   matlab.ui.control.Label
        QueryByFrameEditField          matlab.ui.control.NumericEditField
        QueryByFrameLabel              matlab.ui.control.Label
        LickNumberField                matlab.ui.control.NumericEditField
        LickMinus                      matlab.ui.control.Button
        LickPlus                       matlab.ui.control.Button
        LickLabel                      matlab.ui.control.Label
        FrameNumberField               matlab.ui.control.NumericEditField
        FrameMinus                     matlab.ui.control.Button
        FramePlus                      matlab.ui.control.Button
        MaxFrameLabel                  matlab.ui.control.Label
        MaxDisplacementLabel           matlab.ui.control.Label
        MaxProtractionSpeedLabel       matlab.ui.control.Label
        WithinlickframeLabel           matlab.ui.control.Label
        DisplacementmmLabel            matlab.ui.control.Label
        VelocitymmsLabel               matlab.ui.control.Label
        EndPointAngleLabel             matlab.ui.control.Label
        TipAngleLabel                  matlab.ui.control.Label
        LickDurationLabel              matlab.ui.control.Label
        TimeElapsedLabel               matlab.ui.control.Label
        ReclassifyButton               matlab.ui.control.Button
        VideoFrameLabel                matlab.ui.control.Label
        SummaryPlot                    matlab.ui.control.UIAxes
        GeoPlot                        matlab.ui.control.UIAxes
        VidFrame                       matlab.ui.control.UIAxes
        PositionPlot                   matlab.ui.control.UIAxes
        VelocityPlot                   matlab.ui.control.UIAxes
        EndPositionPlot                matlab.ui.control.UIAxes
        TracePlot                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        analyze_done
        qa_done
        save_done
        
        vidfile_name
        vidfile_path
        VidObj
        
        DLC
        DLC_mat
        points_backup
        points_qa
        params
        funcs

        time_vid
        time_1K
        curr_inds_vid
        curr_inds_1K
        ind_lick_onset_vid
        ind_lick_offset_vid
        lickNumPrev
        frameNumPrev
        foodMarkersNumPrev
        curr_class
        RECLASSIFIED
        hFig

        end_position
        velocity_position
        distance_position
        summary_position
        ENDPOSITION
        summaryLeg

    end
    
    properties (Access = public)
        done
        LICKS_ALL_DATA
        EXPERIMENT_PARAMS
    end
    
    methods (Access = private)
        function grabFrame(app)
            %% Grab frames from video
            n_lick = app.LickNumberField.Value;
            ind_lick_onset = app.ind_lick_onset_vid(n_lick);
            ind_lick_offset = app.ind_lick_offset_vid(n_lick);

            app.curr_inds_vid = ind_lick_onset:ind_lick_offset;
            times = app.time_vid(app.curr_inds_vid);
            
            app.curr_inds_1K = [];
            for i = 1:length(times)
                [~, ind] = min(abs(app.time_1K - times(i)));
                app.curr_inds_1K = [app.curr_inds_1K ind];
            end
        end
                
        function displayVidFrame(app, value)
            %% Display video frame
            frame = read(app.VidObj, value);        
            
            app.VidFrame.Position(3) = app.VidObj.Width;
            app.VidFrame.Position(4) = app.VidObj.Height;
            I = imshow(frame, 'Parent', app.VidFrame, ...
                'XData', [1 app.VidFrame.Position(3)], 'YData', [1 app.VidFrame.Position(4)]);
                    
            %Set limits of axes            
            app.VidFrame.XLim = [-10 I.XData(2)+10];
            app.VidFrame.YLim = [-10 I.YData(2)+10];
            
            % Plot points on axes
            tip_tongue_x = app.points_qa.tip_tongue_x;
            tip_tongue_y = app.points_qa.tip_tongue_y;
            r_tongue_x = app.points_qa.r_tongue_x;
            r_tongue_y = app.points_qa.r_tongue_y;
            l_tongue_x = app.points_qa.l_tongue_x;
            l_tongue_y = app.points_qa.l_tongue_y;
            mid_tongue_x = app.points_qa.mid_tongue_x;
            mid_tongue_y = app.points_qa.mid_tongue_y;
            r_nose_x = app.points_qa.r_nose_x;
            r_nose_y = app.points_qa.r_nose_y;
            l_nose_x = app.points_qa.l_nose_x;
            l_nose_y = app.points_qa.l_nose_y;
            r_food_x = app.points_qa.r_food_x;
            r_food_y = app.points_qa.r_food_y;
            r_tube_r_x = app.points_qa.r_tube_r_x;
            r_tube_r_y = app.points_qa.r_tube_r_y;
            r_tube_l_x = app.points_qa.r_tube_l_x;
            r_tube_l_y = app.points_qa.r_tube_l_y;           
            l_food_x = app.points_qa.l_food_x;
            l_food_y = app.points_qa.l_food_y;
            l_tube_r_x = app.points_qa.l_tube_r_x;
            l_tube_r_y = app.points_qa.l_tube_r_y;
            l_tube_l_x = app.points_qa.l_tube_l_x;
            l_tube_l_y = app.points_qa.l_tube_l_y;

            tip_tongue_color = [1 0 0];
            l_tongue_color = [0 1 0];
            r_tongue_color = [0 1 1];
            mid_tongue_color = [0 0 1];
            r_food_color = [1 1 0];
            r_tube_r_color = [1 1 1];
            r_tube_l_color = [1 0 1];
            r_nose_color = [101 67 33]/255;
            l_food_color = [250 128 114]/255;
            l_tube_r_color = [167 167 167]/255;
            l_tube_l_color = [138 43 226]/255;
            l_nose_color = [0 0 0];

            function plotpoints(pos, color)
                roi = drawpoint(app.VidFrame, 'Color', color, 'Position', [pos(1) pos(2)]);
                addlistener(roi,'ROIClicked',@allevents);
            end
            
            plotpoints([tip_tongue_x(value) tip_tongue_y(value)], tip_tongue_color)
            plotpoints([l_tongue_x(value) l_tongue_y(value)], l_tongue_color)
            plotpoints([r_tongue_x(value) r_tongue_y(value)], r_tongue_color)
            plotpoints([mid_tongue_x(value) mid_tongue_y(value)], mid_tongue_color)
            plotpoints([r_food_x(value) r_food_y(value)], r_food_color)
            plotpoints([r_tube_r_x(value) r_tube_r_y(value)], r_tube_r_color)
            plotpoints([r_tube_l_x(value) r_tube_l_y(value)], r_tube_l_color)
            plotpoints([r_nose_x(value) r_nose_y(value)], r_nose_color)
            plotpoints([l_nose_x(value) l_nose_y(value)], l_nose_color)
            plotpoints([l_food_x(value) l_food_y(value)], l_food_color)
            plotpoints([l_tube_r_x(value) l_tube_r_y(value)], l_tube_r_color)
            plotpoints([l_tube_l_x(value) l_tube_l_y(value)], l_tube_l_color)

            % listener for manual curation
            function allevents(src,evt)
                app.analyze_done = false;
                app.params.lick.qa = 1;
                app.qa_done = true;

                % open figure for indicating new pos
                fig = figure;
                fig.Position(3) = app.VidObj.Width;
                fig.Position(4) = app.VidObj.Height;
                imshow(frame);
                hold on
                plot(src.Position(1), src.Position(2), '*w')
                [x,y] = getpts;
                close(fig);

                % move point
                src.Position = [x, y];

                % find out which point this is and update
                if all(src.Color == tip_tongue_color)
                    app.DLC.POINTS.tip_tongue_x(value) = x;
                    app.DLC.POINTS.tip_tongue_y(value) = y;
                    app.points_qa.tip_tongue_x(value) = x;
                    app.points_qa.tip_tongue_y(value) = y;
                elseif all(src.Color == l_tongue_color)
                    app.DLC.POINTS.l_tongue_x(value) = x;
                    app.DLC.POINTS.l_tongue_y(value) = y;
                    app.points_qa.l_tongue_x(value) = x;
                    app.points_qa.l_tongue_y(value) = y;
                elseif all(src.Color == r_tongue_color)
                    app.DLC.POINTS.r_tongue_x(value) = x;
                    app.DLC.POINTS.r_tongue_y(value) = y;
                    app.points_qa.r_tongue_x(value) = x;
                    app.points_qa.r_tongue_y(value) = y;
                elseif all(src.Color == mid_tongue_color)
                    app.DLC.POINTS.mid_tongue_x(value) = x;
                    app.DLC.POINTS.mid_tongue_y(value) = y;
                    app.points_qa.mid_tongue_x(value) = x;
                    app.points_qa.mid_tongue_y(value) = y;
                elseif all(src.Color == r_food_color)
                    app.DLC.POINTS.r_food_x(value) = x;
                    app.DLC.POINTS.r_food_y(value) = y;
                    app.points_qa.r_food_x(value) = x;
                    app.points_qa.r_food_y(value) = y;
                elseif all(src.Color == r_tube_r_color)
                    app.DLC.POINTS.r_tube_r_x(value) = x;
                    app.DLC.POINTS.r_tube_r_y(value) = y;
                    app.points_qa.r_tube_r_x(value) = x;
                    app.points_qa.r_tube_r_y(value) = y;
                elseif all(src.Color == r_tube_l_color)
                    app.DLC.POINTS.r_tube_l_x(value) = x;
                    app.DLC.POINTS.r_tube_l_y(value) = y;
                    app.points_qa.r_tube_l_x(value) = x;
                    app.points_qa.r_tube_l_y(value) = y;
                elseif all(src.Color == r_nose_color)
                    app.DLC.POINTS.r_nose_x(value) = x;
                    app.DLC.POINTS.r_nose_y(value) = y;
                    app.points_qa.r_nose_x(value) = x;
                    app.points_qa.r_nose_y(value) = y;
                elseif all(src.Color == l_food_color)
                    app.DLC.POINTS.l_food_x(value) = x;
                    app.DLC.POINTS.l_food_y(value) = y;
                    app.points_qa.l_food_x(value) = x;
                    app.points_qa.l_food_y(value) = y;
                elseif all(src.Color == l_tube_r_color)
                    app.DLC.POINTS.l_tube_r_x(value) = x;
                    app.DLC.POINTS.l_tube_r_y(value) = y;
                    app.points_qa.l_tube_r_x(value) = x;
                    app.points_qa.l_tube_r_y(value) = y;
                elseif all(src.Color == l_tube_l_color)
                    app.DLC.POINTS.l_tube_l_x(value) = x;
                    app.DLC.POINTS.l_tube_l_y(value) = y;
                    app.points_qa.l_tube_l_x(value) = x;
                    app.points_qa.l_tube_l_y(value) = y;
                elseif all(src.Color == l_nose_color)
                    app.DLC.POINTS.l_nose_x(value) = x;
                    app.DLC.POINTS.l_nose_y(value) = y;
                    app.points_qa.l_nose_x(value) = x;
                    app.points_qa.l_nose_y(value) = y;
                end

                updateDropDownFrames(app, value);
            end
        end

        function lickNumChanged(app)
            grabFrame(app)
            displayClass(app);
            plotLickVelocityAndDistanceProfiles(app);
            editEndPositionPlot(app);
            [~, app.FrameNumberField.Value] = min(abs(app.curr_inds_1K - app.DLC.IND.ind_d_lick_max(app.LickNumberField.Value)));
            app.MaxDisplacementLabel.Text = "Max Displacement (mm): " + num2str(app.DLC.KINEMATIC.d_lick_max(app.LickNumberField.Value, :), "%.0f");
            app.MaxProtractionSpeedLabel.Text = "Max Protration Speed (mm/s): " + num2str(app.DLC.KINEMATIC.v_lick_max(app.LickNumberField.Value, :), "%.0f");
            app.MaxRetractionSpeedLabel.Text = "Max Retraction Speed (mm/s): " + num2str(abs(app.DLC.KINEMATIC.v_lick_min(app.LickNumberField.Value, :)), "%.0f");
            app.EndPointAngleLabel.Text = "Max End Point Angle (°): " + num2str(max(app.DLC.KINEMATIC.angle_lick(app.LickNumberField.Value, :)), "%.0f");
            app.MaxFrameLabel.Text = "Total # of Frames: " + int2str(length(app.curr_inds_1K));
            app.LickDurationLabel.Text = "Lick Duration (ms): " + num2str((1000 * (app.DLC.TIME.time_lick_duration(app.LickNumberField.Value))), "%.0f");
            frameNumChanged(app)
        end
        
        function frameNumChanged(app)
            displayVidFrame(app, app.curr_inds_vid(app.FrameNumberField.Value))
            plotTraces(app)
            plotGeo(app)    
            lick_frame_ind = app.curr_inds_1K(app.FrameNumberField.Value) - app.curr_inds_1K(1);
            app.DisplacementmmLabel.Text = "Distance (mm): " + num2str(app.DLC.KINEMATIC.d_lick(app.LickNumberField.Value, lick_frame_ind), "%.0f");
            app.VelocitymmsLabel.Text = "Velocity (mm/s): " + num2str(app.DLC.KINEMATIC.v_lick(app.LickNumberField.Value, lick_frame_ind), "%.0f");
            app.TipAngleLabel.Text = "Tip Angle (°): " + num2str(app.DLC.KINEMATIC.angle_lick(app.LickNumberField.Value, lick_frame_ind),"%.0f");
            app.TimeElapsedLabel.Text = "Time Elapsed (ms): " + num2str(1000* (app.DLC.TIME.time_1K(app.curr_inds_1K(app.FrameNumberField.Value))- app.DLC.TIME.time_lick_onset(app.LickNumberField.Value)), "%.0f");
            editLickVelocityAndDistanceProfiles(app)
            editSummaryPlot(app)
            setQueryByFrame(app)
            setDropDownItem(app)
         end

        function displayClass(app)
            app.curr_class = setClassfromDLC(app);
            setCheckBoxesFromClass(app, app.curr_class);
        end
        
        function setQueryByFrame(app)
            if strcmp(app.QueryByFrameWarningLabel.Text, 'This frame is not in any lick')
                app.QueryByFrameWarningLabel.Text = "";
            end  
            app.QueryByFrameEditField.Value = app.curr_inds_vid(app.FrameNumberField.Value);
        end

        function setDropDownItem(app)
            if app.DropDown.Visible
                array = zeros([length(app.DropDown.Items)-1, 1]);
                for i = 1:length(app.DropDown.Items)-1
                    array(i) = str2double(app.DropDown.Items{i+1});
                end
                ind = find(array == app.curr_inds_vid(app.FrameNumberField.Value));
                if isempty(ind)
                    app.DropDown.Value = app.DropDown.Items{1,1};
                else
                    app.DropDown.Value = app.DropDown.Items{1,ind + 1};
                end
            end
        end

        function updateDropDownFrames(app, inds)
            % Display frames in drop down menu
            if app.DropDown.Visible
                % there are existing items
                % convert existing items to array
                array = zeros([length(app.DropDown.Items)-1, 1]);
                for i = 1:length(app.DropDown.Items)-1
                    array(i) = str2double(app.DropDown.Items{i+1});
                end
                % append new values to array and sort
                array = unique(sort([array; inds]));
                % convert array to cell array, add default item to front
                app.DropDown.Items = [{'------------'}; sprintfc('%d', array)];
            else
                app.DropDown.Items = [{'------------'}; sprintfc('%d', sort(inds))];
                app.DropDown.Visible = true;
                app.BrowseframesmodifiedbyQALabel.Visible = true;
            end
            setDropDownItem(app)
        end
        
        function plotLickVelocityAndDistanceProfiles(app)
            cla(app.PositionPlot)
            cla(app.VelocityPlot)
            
            num_lick = app.LickNumberField.Value;
            v_lick = app.DLC.KINEMATIC.v_lick;
            d_lick = app.DLC.KINEMATIC.d_lick;
            time_lick_duration = app.DLC.TIME.time_lick_duration;
            
            app.PositionPlot.XLim = [0 max(time_lick_duration(num_lick))*1000 + 0.1 * max(time_lick_duration(num_lick))*1000];
            app.VelocityPlot.XLim = [0 max(time_lick_duration(num_lick))*1000 + 0.1 * max(time_lick_duration(num_lick))*1000];
            app.PositionPlot.YLim = [0 max(d_lick(num_lick,:)) + 0.1 * max(d_lick(num_lick,:))];
            app.VelocityPlot.YLim = [min(v_lick(num_lick,:)) + 0.1 * min(v_lick(num_lick,:)) max(v_lick(num_lick,:)) + 0.1 * max(v_lick(num_lick,:))];
            
            hold(app.PositionPlot,'on');
            plot(app.PositionPlot, app.time_1K(1:length(d_lick(num_lick,:)))*1000,d_lick(num_lick,:));            
            
            hold(app.VelocityPlot,'on');
            plot(app.VelocityPlot, app.time_1K(1:length(v_lick(num_lick,:)))*1000,v_lick(num_lick,:));
        end

        function plotTraces(app)
            %%clear plot
            cla(app.TracePlot);

            %% Grab traces from DLC
            tip_tongue_x = app.DLC.POINTS.tip_tongue_x;
            tip_tongue_y = app.DLC.POINTS.tip_tongue_y;
            mid_tongue_x = app.DLC.POINTS.mid_tongue_x;
            mid_tongue_y = app.DLC.POINTS.mid_tongue_y;
            l_tongue_x = app.DLC.POINTS.l_tongue_x;
            l_tongue_y = app.DLC.POINTS.l_tongue_y;
            r_tongue_x = app.DLC.POINTS.r_tongue_x;
            r_tongue_y = app.DLC.POINTS.r_tongue_y;
            r_nose_x =  app.DLC.POINTS.r_nose_x;
            r_nose_y = app.DLC.POINTS.r_nose_y;
            l_nose_x =  app.DLC.POINTS.l_nose_x;
            l_nose_y = app.DLC.POINTS.l_nose_y;

            r_tube_r_x = app.DLC.POINTS.r_tube_r_x;
            r_tube_r_y = app.DLC.POINTS.r_tube_r_y;
            r_tube_l_x = app.DLC.POINTS.r_tube_l_x;
            r_tube_l_y = app.DLC.POINTS.r_tube_l_y;
            l_tube_r_x = app.DLC.POINTS.l_tube_r_x;
            l_tube_r_y = app.DLC.POINTS.l_tube_r_y;
            l_tube_l_x = app.DLC.POINTS.l_tube_l_x;
            l_tube_l_y = app.DLC.POINTS.l_tube_l_y;

            ind_d_lick_end = app.DLC.IND.ind_d_lick_max;
            frame_num = app.FrameNumberField.Value;
            
            hold(app.TracePlot,'on');
            plot(app.TracePlot, tip_tongue_x(app.curr_inds_1K(1):app.curr_inds_1K(frame_num)),tip_tongue_y(app.curr_inds_1K(1):app.curr_inds_1K(frame_num)), 'r.-', 'Linewidth', 0.8);
            plot(app.TracePlot, mid_tongue_x(app.curr_inds_1K(1):app.curr_inds_1K(frame_num)),mid_tongue_y(app.curr_inds_1K(1):app.curr_inds_1K(frame_num)), 'b.-', 'Linewidth', 0.8);
            plot(app.TracePlot, r_tongue_x(app.curr_inds_1K(1):app.curr_inds_1K(frame_num)),r_tongue_y(app.curr_inds_1K(1):app.curr_inds_1K(frame_num)), 'c.-', 'Linewidth', 0.8);
            plot(app.TracePlot, l_tongue_x(app.curr_inds_1K(1):app.curr_inds_1K(frame_num)),l_tongue_y(app.curr_inds_1K(1):app.curr_inds_1K(frame_num)), 'g.-', 'Linewidth', 0.8);
            plot(app.TracePlot, mean(r_nose_x(ind_d_lick_end)),mean(r_nose_y(ind_d_lick_end)),'ok');
            plot(app.TracePlot, mean(l_nose_x(ind_d_lick_end)),mean(l_nose_y(ind_d_lick_end)),'ok');         
            plot(app.TracePlot, mean(r_tube_r_x(ind_d_lick_end)),mean(r_tube_r_y(ind_d_lick_end)),'sk');
            plot(app.TracePlot, mean(r_tube_l_x(ind_d_lick_end)),mean(r_tube_l_y(ind_d_lick_end)),'sk');
            plot(app.TracePlot, mean(l_tube_r_x(ind_d_lick_end)),mean(l_tube_r_y(ind_d_lick_end)),'sk');
            plot(app.TracePlot, mean(l_tube_l_x(ind_d_lick_end)),mean(l_tube_l_y(ind_d_lick_end)),'sk');
            line(app.TracePlot, [mean(r_tube_r_x(ind_d_lick_end)) mean(r_tube_r_x(ind_d_lick_end))], [mean(r_tube_r_y(ind_d_lick_end)) 20], 'Color', 'k');
            line(app.TracePlot, [mean(r_tube_l_x(ind_d_lick_end)) mean(r_tube_l_x(ind_d_lick_end))], [mean(r_tube_l_y(ind_d_lick_end)) 20], 'Color', 'k');
            line(app.TracePlot, [mean(l_tube_r_x(ind_d_lick_end)) mean(l_tube_r_x(ind_d_lick_end))], [mean(l_tube_r_y(ind_d_lick_end)) -20], 'Color', 'k');
            line(app.TracePlot, [mean(l_tube_l_x(ind_d_lick_end)) mean(l_tube_l_x(ind_d_lick_end))], [mean(l_tube_l_y(ind_d_lick_end)) -20], 'Color', 'k');
            line(app.TracePlot, [mean(l_tube_r_x(ind_d_lick_end)) mean(l_tube_l_x(ind_d_lick_end))], [mean(l_tube_r_y(ind_d_lick_end)) mean(l_tube_l_y(ind_d_lick_end))], 'Color', 'k');
            line(app.TracePlot, [mean(r_tube_r_x(ind_d_lick_end)) mean(r_tube_l_x(ind_d_lick_end))], [mean(r_tube_r_y(ind_d_lick_end)) mean(r_tube_l_y(ind_d_lick_end))], 'Color', 'k');

            leg = legend(app.TracePlot, 'tip', 'mid', 'left', 'right', '','','','','','','','','','', '','');
            leg.ItemTokenSize = [1,1];
            leg.FontSize = 8;
        end
        
        function plotGeo(app)  
            %%clear plot
            cla(app.GeoPlot);
            
            %% Grab frames from geo
            tip_tongue_x = app.DLC.POINTS.tip_tongue_x;
            tip_tongue_y = app.DLC.POINTS.tip_tongue_y;
            r_tongue_x = app.DLC.POINTS.r_tongue_x;
            r_tongue_y = app.DLC.POINTS.r_tongue_y;
            l_tongue_x = app.DLC.POINTS.l_tongue_x;
            l_tongue_y = app.DLC.POINTS.l_tongue_y;
            mid_tongue_x = app.DLC.POINTS.mid_tongue_x;
            mid_tongue_y = app.DLC.POINTS.mid_tongue_y;
            r_nose_x =  app.DLC.POINTS.r_nose_x;
            r_nose_y = app.DLC.POINTS.r_nose_y;
            l_nose_x =  app.DLC.POINTS.l_nose_x;
            l_nose_y = app.DLC.POINTS.l_nose_y;

            r_tube_r_x = app.DLC.POINTS.r_tube_r_x;
            r_tube_r_y = app.DLC.POINTS.r_tube_r_y;
            r_tube_l_x = app.DLC.POINTS.r_tube_l_x;
            r_tube_l_y = app.DLC.POINTS.r_tube_l_y;
            r_food_x = app.DLC.POINTS.r_food_x;
            r_food_y = app.DLC.POINTS.r_food_y;
            l_tube_r_x = app.DLC.POINTS.l_tube_r_x;
            l_tube_r_y = app.DLC.POINTS.l_tube_r_y;
            l_tube_l_x = app.DLC.POINTS.l_tube_l_x;
            l_tube_l_y = app.DLC.POINTS.l_tube_l_y;
            l_food_x = app.DLC.POINTS.l_food_x;
            l_food_y = app.DLC.POINTS.l_food_y;
            
            ind = app.curr_inds_1K(app.FrameNumberField.Value);
            geo_tongue = polyshape([tip_tongue_x(ind),l_tongue_x(ind),...
            mid_tongue_x(ind),r_tongue_x(ind)],[tip_tongue_y(ind),...
            l_tongue_y(ind),mid_tongue_y(ind),r_tongue_y(ind)]);
    
            geo_r_tube_empty = polyshape([r_food_x(ind),r_tube_r_x(ind),...
                r_tube_r_x(ind),r_tube_l_x(ind), r_tube_l_x(ind)],[r_food_y(ind),...
                r_food_y(ind), r_tube_r_y(ind),r_tube_l_y(ind), r_food_y(ind)]);
        
            geo_r_tube_full = polyshape([r_tube_r_x(ind), r_tube_r_x(ind),...
                r_tube_l_x(ind), r_tube_l_x(ind), r_food_x(ind)],...
                [r_food_y(ind), r_tube_r_y(ind) + abs(max(r_food_y)-min(r_food_y)), ...
                r_tube_l_y(ind) + abs(max(r_food_y)-min(r_food_y)), r_food_y(ind), r_food_y(ind)]);  
           
            geo_inter_tongue_r_tube_empty = intersect(geo_tongue, geo_r_tube_empty);
        
            geo_inter_tongue_r_tube_full = intersect(geo_tongue, geo_r_tube_full);

            geo_l_tube_empty = polyshape([l_food_x(ind),l_tube_r_x(ind),...
                l_tube_r_x(ind),l_tube_l_x(ind), l_tube_l_x(ind)],[l_food_y(ind),...
                l_food_y(ind), l_tube_r_y(ind),l_tube_l_y(ind), l_food_y(ind)]);
    
            geo_l_tube_full = polyshape([l_tube_r_x(ind), l_tube_r_x(ind),...
                l_tube_l_x(ind), l_tube_l_x(ind), l_food_x(ind)],...
                [l_food_y(ind), l_tube_r_y(ind) - abs(max(l_food_y)-min(l_food_y)), ...
                l_tube_l_y(ind) - abs(max(l_food_y)-min(l_food_y)), l_food_y(ind), l_food_y(ind)]);  
    
            geo_inter_tongue_l_tube_empty = intersect(geo_tongue, geo_l_tube_empty);
    
            geo_inter_tongue_l_tube_full = intersect(geo_tongue, geo_l_tube_full);
        
            [cent_tongue_r_tube_empty_x, cent_tongue_r_tube_empty_y ] = centroid(geo_inter_tongue_r_tube_empty);
            [cent_tongue_r_tube_full_x, cent_tongue_r_tube_full_y] = centroid(geo_inter_tongue_r_tube_full);
            [cent_tongue_l_tube_empty_x,cent_tongue_l_tube_empty_y]  = centroid(geo_inter_tongue_l_tube_empty);
            [cent_tongue_l_tube_full_x,cent_tongue_l_tube_full_y] = centroid(geo_inter_tongue_l_tube_full);

            hold(app.GeoPlot,'on');
            plot(app.GeoPlot, geo_tongue, 'LineWidth', 1, 'FaceColor','red');
            plot(app.GeoPlot, geo_r_tube_empty,'LineWidth', 1, 'FaceColor','white');
            plot(app.GeoPlot, geo_r_tube_full,'LineWidth', 1, 'FaceColor','yellow');
            plot(app.GeoPlot, geo_inter_tongue_r_tube_empty,'LineWidth', 1, 'FaceColor','black');
            plot(app.GeoPlot, geo_inter_tongue_r_tube_full,'LineWidth', 1, 'FaceColor','blue');
            plot(app.GeoPlot, cent_tongue_r_tube_empty_x, cent_tongue_r_tube_empty_y,'*m','markersize', 7 );
            plot(app.GeoPlot, cent_tongue_r_tube_full_x, cent_tongue_r_tube_full_y,'*m','markersize', 7);
            plot(app.GeoPlot, tip_tongue_x(ind),tip_tongue_y(ind),'or','markersize', 7);
            plot(app.GeoPlot, r_tongue_x(ind),r_tongue_y(ind),'oc', 'markersize', 7);
            plot(app.GeoPlot, l_tongue_x(ind),l_tongue_y(ind),'og','markersize', 7);
            plot(app.GeoPlot, mid_tongue_x(ind),mid_tongue_y(ind),'ob','markersize', 7);
            plot(app.GeoPlot, r_nose_x(ind),r_nose_y(ind),'ok','markersize', 5);
            plot(app.GeoPlot, l_nose_x(ind),l_nose_y(ind),'ok','markersize', 5);
            plot(app.GeoPlot, geo_l_tube_empty,'LineWidth', 1, 'FaceColor','white');
            plot(app.GeoPlot, geo_l_tube_full,'LineWidth', 1, 'FaceColor','yellow');
            plot(app.GeoPlot, geo_inter_tongue_l_tube_empty,'LineWidth', 1, 'FaceColor','black');
            plot(app.GeoPlot, geo_inter_tongue_l_tube_full,'LineWidth', 1, 'FaceColor','blue');
            plot(app.GeoPlot, cent_tongue_l_tube_empty_x, cent_tongue_l_tube_empty_y,'*m','markersize', 7);
            plot(app.GeoPlot, cent_tongue_l_tube_full_x, cent_tongue_l_tube_full_y,'*m','markersize', 7);
        end
        
        function plotSummary(app)
            yyaxis(app.SummaryPlot, "right")  
            cla(app.SummaryPlot)
            yyaxis(app.SummaryPlot, "left")  
            cla(app.SummaryPlot)

            time_1K = app.DLC.TIME.time_1K;
            d_tip = app.DLC.KINEMATIC.d_tip;
            ind_lick_onset_str_bout = app.DLC.IND.ind_lick_onset_str_bout;
            ind_lick_onset_end_bout = app.DLC.IND.ind_lick_onset_end_bout;
            ind_lick_onset = app.DLC.IND.ind_lick_onset;
            ind_lick_offset = app.DLC.IND.ind_lick_offset;

            time_lick_onset_str_harvest = app.DLC.TIME.time_lick_onset_str_harvest;
            time_lick_onset_end_harvest = app.DLC.TIME.time_lick_onset_end_harvest;
            time_lick_onset_r_reward = app.DLC.TIME.time_lick_onset_r_reward;
            time_lick_onset_l_reward = app.DLC.TIME.time_lick_onset_l_reward;
            r_tube_food = app.DLC.FOOD.r_tube_food;
            l_tube_food = app.DLC.FOOD.l_tube_food;

            hold(app.SummaryPlot, 'on');
            ylim(app.SummaryPlot, [0 30])
            plot(app.SummaryPlot, time_1K,d_tip,'-k', 'DisplayName', 'tip displacement');
            plot(app.SummaryPlot, time_1K(ind_lick_onset_str_bout), d_tip(ind_lick_onset_str_bout), 'or', 'MarkerSize',10, 'DisplayName', 'bout onset');
            plot(app.SummaryPlot, time_1K(ind_lick_onset_end_bout), d_tip(ind_lick_onset_end_bout), 'ob', 'MarkerSize',10,'DisplayName', 'bout offset' );
            plot(app.SummaryPlot, time_1K(ind_lick_onset), d_tip(ind_lick_onset), '.m', 'MarkerSize',10, 'DisplayName', 'lick onset' );
            plot(app.SummaryPlot, time_1K(ind_lick_offset), d_tip(ind_lick_onset), '.c', 'MarkerSize',10, 'DisplayName', 'lick offset');
            xlabel(app.SummaryPlot, 'Time (s)');
            ylabel(app.SummaryPlot, 'Displacement (mm)');

            % harvest
            if app.ShowHarvestCheckBox.Value
                yyaxis(app.SummaryPlot, "right")            
                plot(app.SummaryPlot, time_1K,r_tube_food,'.-r', 'DisplayName', 'right tube food level');
                plot(app.SummaryPlot, time_1K,l_tube_food,'.-b', 'DisplayName', 'left tube food level');
                ylabel(app.SummaryPlot, 'Reward capacity')

                yyaxis(app.SummaryPlot, "left")
                if ~isempty(time_lick_onset_r_reward)
                    plot(app.SummaryPlot, time_lick_onset_r_reward,19,'.r');
                end
                if ~isempty(time_lick_onset_l_reward)
                    plot(app.SummaryPlot, time_lick_onset_l_reward,19,'.b');
                end
                plot(app.SummaryPlot, time_lick_onset_str_harvest,20,'*g');
                plot(app.SummaryPlot, time_lick_onset_end_harvest,20,'*r');
            end

            % create legend
            if ~app.ShowHarvestCheckBox.Value
                h = zeros(5, 1);
                h(1) = plot(app.SummaryPlot, NaN,NaN,'-k');
                h(2) = plot(app.SummaryPlot,NaN,NaN,'.m');
                h(3) = plot(app.SummaryPlot,NaN,NaN,'.c');
                h(4) = plot(app.SummaryPlot,NaN,NaN,'or');
                h(5) = plot(app.SummaryPlot,NaN,NaN,'ob');
                app.summaryLeg = legend(h, 'tip displacement', 'lick onset', ...
                    'lick offset', 'bout onset', 'bout offset',...
                     'location', 'north', 'orientation', 'horizontal');
            else
                h = zeros(11, 1);
                h(1) = plot(app.SummaryPlot,NaN,NaN,'-k');
                h(2) = plot(app.SummaryPlot,NaN,NaN,'.m');
                h(3) = plot(app.SummaryPlot,NaN,NaN,'.c');
                h(4) = plot(app.SummaryPlot,NaN,NaN,'or');
                h(5) = plot(app.SummaryPlot,NaN,NaN,'ob');
                h(6) = plot(app.SummaryPlot,NaN,NaN,'.-r');
                h(7) = plot(app.SummaryPlot,NaN,NaN,'.-b');
                h(8) = plot(app.SummaryPlot,NaN,NaN,'.r');
                h(9) = plot(app.SummaryPlot,NaN,NaN,'.b');
                h(10) = plot(app.SummaryPlot,NaN,NaN,'*g');
                h(11) = plot(app.SummaryPlot,NaN,NaN,'*r');
                app.summaryLeg = legend(h, 'tip displacement', 'lick onset', 'lick offset', 'bout onset',...
                'bout offset', 'right tube food level', 'left tube food level', ...
                'right rewarded', 'left rewarded', 'harvest onset', 'harvest offset',...
                'location', 'north', 'orientation', 'horizontal');
            end
            app.summaryLeg.ItemTokenSize = [7, 7];
            app.summaryLeg.FontSize = 7;  
            app.summaryLeg.NumColumns = 3;
            if app.ShowLegendCheckBox.Value
                set(app.summaryLeg,'visible','on')
            else
                set(app.summaryLeg,'visible','off')
            end
            editSummaryPlot(app)
        end

        function plotEndPositionPlot(app)
            cla(app.EndPositionPlot)
            tip_tongue_x = app.DLC.POINTS.tip_tongue_x;
            tip_tongue_y = app.DLC.POINTS.tip_tongue_y;
            r_nose_x =  app.DLC.POINTS.r_nose_x;
            r_nose_y = app.DLC.POINTS.r_nose_y;
            l_nose_x =  app.DLC.POINTS.l_nose_x;
            l_nose_y = app.DLC.POINTS.l_nose_y;
            r_tube_r_x = app.DLC.POINTS.r_tube_r_x;
            r_tube_r_y = app.DLC.POINTS.r_tube_r_y;
            r_tube_l_x = app.DLC.POINTS.r_tube_l_x;
            r_tube_l_y = app.DLC.POINTS.r_tube_l_y;
            l_tube_r_x = app.DLC.POINTS.l_tube_r_x;
            l_tube_r_y = app.DLC.POINTS.l_tube_r_y;
            l_tube_l_x = app.DLC.POINTS.l_tube_l_x;
            l_tube_l_y = app.DLC.POINTS.l_tube_l_y;

            ind_d_lick_max = app.DLC.IND.ind_d_lick_max;
            is_grooming_lick = app.DLC.CLASS.is_grooming_lick;
            is_r_reward_inner_tube_lick = app.DLC.CLASS.is_r_reward_inner_tube_lick;
            is_r_reward_outer_edge_lick = app.DLC.CLASS.is_r_reward_outer_edge_lick;
            is_r_reward_under_tube_lick = app.DLC.CLASS.is_r_reward_under_tube_lick;
            is_r_noreward_inner_tube_lick = app.DLC.CLASS.is_r_noreward_inner_tube_lick;
            is_r_noreward_outer_edge_lick = app.DLC.CLASS.is_r_noreward_outer_edge_lick;
            is_r_noreward_under_tube_lick = app.DLC.CLASS.is_r_noreward_under_tube_lick;
            is_l_reward_inner_tube_lick = app.DLC.CLASS.is_l_reward_inner_tube_lick;
            is_l_reward_outer_edge_lick = app.DLC.CLASS.is_l_reward_outer_edge_lick;
            is_l_reward_under_tube_lick = app.DLC.CLASS.is_l_reward_under_tube_lick;
            is_l_noreward_inner_tube_lick = app.DLC.CLASS.is_l_noreward_inner_tube_lick;
            is_l_noreward_outer_edge_lick = app.DLC.CLASS.is_l_noreward_outer_edge_lick;
            is_l_noreward_under_tube_lick = app.DLC.CLASS.is_l_noreward_under_tube_lick;
            
            ind_grooming_lick = find(is_grooming_lick);
            % inner reward/noreward
            ind_r_reward_inner_tube_lick = find(is_r_reward_inner_tube_lick);
            ind_r_noreward_inner_tube_lick = find(is_r_noreward_inner_tube_lick);
            ind_l_reward_inner_tube_lick = find(is_l_reward_inner_tube_lick);
            ind_l_noreward_inner_tube_lick = find(is_l_noreward_inner_tube_lick);
            % outer edge reward/noreward
            ind_r_reward_outer_edge_lick = find(is_r_reward_outer_edge_lick);
            ind_r_noreward_outer_edge_lick = find(is_r_noreward_outer_edge_lick);
            ind_l_reward_outer_edge_lick = find(is_l_reward_outer_edge_lick);
            ind_l_noreward_outer_edge_lick = find(is_l_noreward_outer_edge_lick);
            % under tube reward/noreward
            ind_r_reward_under_tube_lick = find(is_r_reward_under_tube_lick);
            ind_r_noreward_under_tube_lick = find(is_r_noreward_under_tube_lick);
            ind_l_reward_under_tube_lick = find(is_l_reward_under_tube_lick);
            ind_l_noreward_under_tube_lick = find(is_l_noreward_under_tube_lick);

            function allevents(src,evt)
                % find out which type of lick
                if all(src.Color == [0 0 1])
                    ind_type_lick = ind_grooming_lick;
                elseif all(src.Color == [0 1 0])
                    ind_type_lick = ind_r_noreward_inner_tube_lick;
                elseif all(src.Color == [1 0 0])
                    ind_type_lick = ind_r_reward_inner_tube_lick;
                elseif all(src.Color == [0 1 1])
                    ind_type_lick = ind_r_noreward_outer_edge_lick;
                elseif all(src.Color == [1 0 1])
                    ind_type_lick = ind_r_reward_outer_edge_lick;
                elseif all(src.Color == [1 1 0])
                    ind_type_lick = ind_r_noreward_under_tube_lick;
                elseif all(src.Color == [0.7 0.7 0.7])
                    ind_type_lick = ind_r_reward_under_tube_lick;
                elseif all(src.Color == [0 1 0.01])
                    ind_type_lick = ind_l_noreward_inner_tube_lick;
                elseif all(src.Color == [1 0 0.01])
                    ind_type_lick = ind_l_reward_inner_tube_lick;
                elseif all(src.Color == [0.01 1 1])
                    ind_type_lick = ind_l_noreward_outer_edge_lick;
                elseif all(src.Color == [1 0.01 1])
                    ind_type_lick = ind_l_reward_outer_edge_lick;
                elseif all(src.Color == [1 1 0.01])
                    ind_type_lick = ind_l_noreward_under_tube_lick;
                elseif all(src.Color == [0.7 0.7 0.71])
                    ind_type_lick = ind_l_reward_under_tube_lick;
                end

                x = tip_tongue_x(ind_d_lick_max(ind_type_lick));
                y = tip_tongue_y(ind_d_lick_max(ind_type_lick));
                ind_x = find(x == src.Position(1));
                ind_y = find(y == src.Position(2));
                if ind_x ~= ind_y
                    error('Fail to find lick')
                end

                app.LickNumberField.Value = ind_type_lick(ind_x);
                lickNumChanged(app)
            end
           
            function rois = plotpoints(ind_type_lick, color)
                x = tip_tongue_x(ind_d_lick_max(ind_type_lick));
                y = tip_tongue_y(ind_d_lick_max(ind_type_lick));
                rois = [];
                for i = 1:length(ind_d_lick_max(ind_type_lick))
                    roi = drawpoint(app.EndPositionPlot, 'Color', color, 'Position', [x(i) y(i)], 'MarkerSize', 4, 'EdgeAlpha', 0.7);
                    addlistener(roi,'ROIClicked',@allevents);
                    rois = [rois; roi];
                end
            end
            hold(app.EndPositionPlot, 'on');
            app.ENDPOSITION.grooming = plotpoints(ind_grooming_lick, [0 0 1]); % blue 
            app.ENDPOSITION.r_noreward_inner = plotpoints(ind_r_noreward_inner_tube_lick, [0 1 0]); % green
            app.ENDPOSITION.r_reward_inner = plotpoints(ind_r_reward_inner_tube_lick, [1 0 0]); % red
            app.ENDPOSITION.r_noreward_outer = plotpoints(ind_r_noreward_outer_edge_lick, [0 1 1]); % cyan
            app.ENDPOSITION.r_reward_outer = plotpoints(ind_r_reward_outer_edge_lick, [1 0 1]); % magenta
            app.ENDPOSITION.r_noreward_under = plotpoints(ind_r_noreward_under_tube_lick, [1 1 0]); % yellow
            app.ENDPOSITION.r_reward_under = plotpoints(ind_r_reward_under_tube_lick, [0.7 0.7 0.7]); % gray
            app.ENDPOSITION.l_noreward_inner = plotpoints(ind_l_noreward_inner_tube_lick, [0 1 0.01]); % green
            app.ENDPOSITION.l_reward_inner = plotpoints(ind_l_reward_inner_tube_lick, [1 0 0.01]); % red
            app.ENDPOSITION.l_noreward_outer = plotpoints(ind_l_noreward_outer_edge_lick, [0.01 1 1]); % cyan
            app.ENDPOSITION.l_reward_outer = plotpoints(ind_l_reward_outer_edge_lick, [1 0.01 1]); % magenta
            app.ENDPOSITION.l_noreward_under = plotpoints(ind_l_noreward_under_tube_lick, [1 1 0.01]); % yellow
            app.ENDPOSITION.l_reward_under = plotpoints(ind_l_reward_under_tube_lick, [0.7 0.7 0.71]); % gray

            plot(app.EndPositionPlot, r_nose_x, r_nose_y,'ok');
            plot(app.EndPositionPlot, l_nose_x, l_nose_y,'ok');
            plot(app.EndPositionPlot, r_tube_r_x(ind_d_lick_max),r_tube_r_y(ind_d_lick_max),'sk');
            plot(app.EndPositionPlot, r_tube_l_x(ind_d_lick_max),r_tube_l_y(ind_d_lick_max),'sk');
            plot(app.EndPositionPlot, l_tube_r_x(ind_d_lick_max), l_tube_r_y(ind_d_lick_max),'sk');
            plot(app.EndPositionPlot, l_tube_l_x(ind_d_lick_max), l_tube_l_y(ind_d_lick_max),'sk');
            
            line(app.EndPositionPlot, [mean(r_tube_r_x) mean(r_tube_r_x)], [mean(r_tube_r_y) 25], 'Color', 'k');
            line(app.EndPositionPlot, [mean(r_tube_l_x) mean(r_tube_l_x)], [mean(r_tube_l_y) 25], 'Color', 'k');
            line(app.EndPositionPlot, [mean(l_tube_r_x) mean(l_tube_r_x)], [mean(l_tube_r_y) -25], 'Color', 'k');
            line(app.EndPositionPlot, [mean(l_tube_l_x) mean(l_tube_l_x)], [mean(l_tube_l_y) -25], 'Color', 'k');
            line(app.EndPositionPlot, [mean(l_tube_r_x) mean(l_tube_l_x)], [mean(l_tube_r_y) mean(l_tube_l_y)], 'Color', 'k');
            line(app.EndPositionPlot, [mean(r_tube_r_x) mean(r_tube_l_x)], [mean(r_tube_r_y) mean(r_tube_l_y)], 'Color', 'k');
            xlabel(app.EndPositionPlot, 'x position (mm)');
            ylabel(app.EndPositionPlot, 'y position (mm)');
            set(app.EndPositionPlot, 'YDir','reverse')
            xlim(app.EndPositionPlot, [-15 15]);
            ylim(app.EndPositionPlot, [-25 25]);

            h = zeros(7, 1);
            h(1) = plot(app.EndPositionPlot, NaN,NaN,'ob');
            h(2) = plot(app.EndPositionPlot,NaN,NaN,'or');
            h(3) = plot(app.EndPositionPlot,NaN,NaN, 'o', 'Color', [0.7 0.7 0.7]);
            h(4) = plot(app.EndPositionPlot,NaN,NaN,'om');
            h(5) = plot(app.EndPositionPlot,NaN,NaN,'og');
            h(6) = plot(app.EndPositionPlot,NaN,NaN,'oy');
            h(7) = plot(app.EndPositionPlot,NaN,NaN,'oc');
            leg = legend(h, 'grooming', 'inner (reward)', ...
                'under (reward)', 'outer (reward)', 'inner (no reward)',...
                'under (no reward)', 'outer (no reward)',...
                 'location', 'northwest');
            leg.ItemTokenSize = [7, 7];
            leg.FontSize = 7;  
            editEndPositionPlot(app)
        end
        
        function editLickVelocityAndDistanceProfiles(app)
            if ~isempty(app.velocity_position)
                delete(app.velocity_position)
            end
            if ~isempty(app.distance_position)
                delete(app.distance_position)
            end
            
            lick_frame_ind = app.curr_inds_1K(app.FrameNumberField.Value) - app.curr_inds_1K(1);
            v_lick = app.DLC.KINEMATIC.v_lick;
            d_lick = app.DLC.KINEMATIC.d_lick;
            num_lick = app.LickNumberField.Value;
            hold(app.PositionPlot, 'on');
            app.distance_position = plot(app.PositionPlot, app.time_1K(lick_frame_ind)*1000,d_lick(num_lick,lick_frame_ind), 'Color', 'r', 'Marker', '*');
            hold(app.VelocityPlot, 'on');
            app.velocity_position = plot(app.VelocityPlot, app.time_1K(lick_frame_ind)*1000,v_lick(num_lick,lick_frame_ind), 'Color', 'r', 'Marker', '*');
        end

        function editSummaryPlot(app)
            if ~isempty(app.summary_position)
                delete(app.summary_position)
            end
            frame_num = app.curr_inds_1K(app.FrameNumberField.Value);
            d_tip = app.DLC.KINEMATIC.d_tip;
            hold(app.SummaryPlot, 'on');
            yyaxis(app.SummaryPlot, "left")
            app.summary_position = plot(app.SummaryPlot, app.time_1K(frame_num), d_tip(frame_num), 'Color', 'r', 'Marker', 'pentagram', 'MarkerSize',10, 'DisplayName', 'current position');
            xlim(app.SummaryPlot, [app.time_1K(frame_num)-3 app.time_1K(frame_num)+3])     
            xticks(app.SummaryPlot, 'auto')
            xticklabels(app.SummaryPlot, 'auto')
        end
        
        function editEndPositionPlot(app)
            if ~isempty(app.end_position)
                delete(app.end_position)
            end
            tip_tongue_x = app.DLC.POINTS.tip_tongue_x;
            tip_tongue_y = app.DLC.POINTS.tip_tongue_y;
            ind_d_lick_max = app.DLC.IND.ind_d_lick_max;

            hold(app.EndPositionPlot, 'on');
            x = tip_tongue_x(ind_d_lick_max(app.LickNumberField.Value));
            y = tip_tongue_y(ind_d_lick_max(app.LickNumberField.Value));
            app.end_position = plot(app.EndPositionPlot, x, y, 'Color', 'r', 'Marker', 'pentagram', 'MarkerSize',10, 'DisplayName', 'current position');         
        end
        
        function class = setClassFromCheckBoxes(app)
            class = [];
            if sum([app.UndertubeCheckBox.Value, app.OuteredgeCheckBox.Value, app.InnertubeCheckBox.Value]) == 1 && ...
                    sum([app.LeftCheckBox.Value, app.RightCheckBox.Value]) == 1 && ...
                    sum([app.RewardCheckBox.Value, app.NorewardCheckBox.Value]) == 1
                % set direction
                if app.LeftCheckBox.Value == 1
                    class.dir = 'l';
                else
                    class.dir = 'r';
                end

                % set reward
                if app.RewardCheckBox.Value == 1
                    class.reward = 1;
                else
                    class.reward = 0;
                end

                % set type
                if app.UndertubeCheckBox.Value == 1
                    class.type = 'u';
                elseif app.OuteredgeCheckBox.Value == 1
                    class.type = 'o';
                else 
                    class.type = 'i';
                end

            elseif sum([app.UndertubeCheckBox.Value, app.OuteredgeCheckBox.Value, app.InnertubeCheckBox.Value, ...
                    app.LeftCheckBox.Value, app.RightCheckBox.Value, app.RewardCheckBox.Value, app.NorewardCheckBox.Value]) == 0
                class.dir = 'g';
                class.reward = false;
                class.type = 'g';
            end
        end
        
        function setCheckBoxesFromClass(app, class)
            app.LeftCheckBox.Value = false;
            app.RightCheckBox.Value = false;
            app.RewardCheckBox.Value = false;
            app.NorewardCheckBox.Value = false;
            app.InnertubeCheckBox.Value = false;
            app.OuteredgeCheckBox.Value = false;
            app.UndertubeCheckBox.Value = false;
            if class.dir ~= 'g'
                if class.dir == 'l'
                    app.LeftCheckBox.Value = true;
                else
                    app.RightCheckBox.Value = true;
                end
                if class.reward
                    app.RewardCheckBox.Value = true;
                else
                    app.NorewardCheckBox.Value = true;
                end
                if class.type == 'i'
                    app.InnertubeCheckBox.Value = true;
                elseif class.type == 'o'
                    app.OuteredgeCheckBox.Value = true;
                else
                    app.UndertubeCheckBox.Value = true;
                end
            end
                
        end

        function class = setClassfromDLC(app)
            class = [];
            if app.DLC.CLASS.is_r_reward_inner_tube_lick(app.LickNumberField.Value)
                class.dir = 'r';
                class.type = 'i';
                class.reward = true;
            elseif app.DLC.CLASS.is_r_noreward_inner_tube_lick(app.LickNumberField.Value)
                class.dir = 'r';
                class.type = 'i';
                class.reward = false;
            elseif app.DLC.CLASS.is_l_reward_inner_tube_lick(app.LickNumberField.Value)
                class.dir = 'l';
                class.type = 'i';
                class.reward = true;
            elseif app.DLC.CLASS.is_l_noreward_inner_tube_lick(app.LickNumberField.Value)
                class.dir = 'l';
                class.type = 'i';
                class.reward = false;
            elseif app.DLC.CLASS.is_r_reward_outer_edge_lick(app.LickNumberField.Value)
                class.dir = 'r';
                class.type = 'o';
                class.reward = true;
            elseif app.DLC.CLASS.is_r_noreward_outer_edge_lick(app.LickNumberField.Value)
                class.dir = 'r';
                class.type = 'o';
                class.reward = false;
            elseif app.DLC.CLASS.is_l_reward_outer_edge_lick(app.LickNumberField.Value)
                class.dir = 'l';
                class.type = 'o';
                class.reward = true;
            elseif app.DLC.CLASS.is_l_noreward_outer_edge_lick(app.LickNumberField.Value)
                class.dir = 'l';
                class.type = 'o';
                class.reward = false;
            elseif app.DLC.CLASS.is_r_reward_under_tube_lick(app.LickNumberField.Value)
                class.dir = 'r';
                class.type = 'u';
                class.reward = true;
            elseif app.DLC.CLASS.is_r_noreward_under_tube_lick(app.LickNumberField.Value)
                class.dir = 'r';
                class.type = 'u';
                class.reward = false;
            elseif app.DLC.CLASS.is_l_reward_under_tube_lick(app.LickNumberField.Value)
                class.dir = 'l';
                class.type = 'u';
                class.reward = true;
            elseif app.DLC.CLASS.is_l_noreward_under_tube_lick(app.LickNumberField.Value)
                class.dir = 'l';
                class.type = 'u';
                class.reward = false;
            else
                class.dir = 'g';
                class.type = 'g';
                class.reward = false;
            end
        end

        function setDLCfromClass(app, class, lick_num)
            % reset DLC.CLASS for this lick
            app.DLC.CLASS.is_r_reward_inner_tube_lick(lick_num) = 0;
            app.DLC.CLASS.is_r_reward_outer_edge_lick(lick_num) = 0;
            app.DLC.CLASS.is_r_reward_under_tube_lick(lick_num) = 0;
            app.DLC.CLASS.is_r_noreward_inner_tube_lick(lick_num) = 0;
            app.DLC.CLASS.is_r_noreward_outer_edge_lick(lick_num) = 0;
            app.DLC.CLASS.is_r_noreward_under_tube_lick(lick_num) = 0;
            app.DLC.CLASS.is_l_reward_inner_tube_lick(lick_num) = 0;
            app.DLC.CLASS.is_l_reward_outer_edge_lick(lick_num) = 0;
            app.DLC.CLASS.is_l_reward_under_tube_lick(lick_num) = 0;
            app.DLC.CLASS.is_l_noreward_inner_tube_lick(lick_num) = 0;
            app.DLC.CLASS.is_l_noreward_outer_edge_lick(lick_num) = 0;
            app.DLC.CLASS.is_l_noreward_under_tube_lick(lick_num) = 0;
            app.DLC.CLASS.is_grooming_lick(lick_num) = 0;

            r_reward_ind = find(app.DLC.CLASS.ind_lick_onset_r_reward == app.curr_inds_1K(1), 1);
            l_reward_ind = find(app.DLC.CLASS.ind_lick_onset_l_reward == app.curr_inds_1K(1), 1);
            if ~isempty(r_reward_ind)
                app.DLC.CLASS.ind_lick_onset_r_reward(r_reward_ind) = [];
                app.DLC.CLASS.ind_lick_offset_r_reward(r_reward_ind) = [];
            elseif ~isempty(l_reward_ind)
                app.DLC.CLASS.ind_lick_onset_l_reward(l_reward_ind) = [];
                app.DLC.CLASS.ind_lick_offset_l_reward(l_reward_ind) = [];
            end
            
            if class.dir == 'g'
                app.DLC.CLASS.is_grooming_lick(lick_num) = 1;
            elseif class.dir == 'l'
                if class.reward
                    if class.type == 'i'
                        app.DLC.CLASS.is_l_reward_inner_tube_lick(lick_num) = 1;
                    elseif class.type == 'u'
                        app.DLC.CLASS.is_l_reward_under_tube_lick(lick_num) = 1;
                    elseif class.type == 'o'
                        app.DLC.CLASS.is_l_reward_outer_edge_lick(lick_num) = 1;
                    end
                    % add to l_reward_ind
                    app.DLC.CLASS.ind_lick_onset_l_reward = sort([app.DLC.CLASS.ind_lick_onset_l_reward; app.curr_inds_1K(1)]);
                    app.DLC.CLASS.ind_lick_offset_l_reward = sort([app.DLC.CLASS.ind_lick_onset_l_reward; app.curr_inds_1K(end)]);
                else
                    if class.type == 'i'
                        app.DLC.CLASS.is_l_noreward_inner_tube_lick(lick_num) = 1;
                    elseif class.type == 'u'
                        app.DLC.CLASS.is_l_noreward_under_tube_lick(lick_num) = 1;
                    elseif class.type == 'o'
                        app.DLC.CLASS.is_l_noreward_outer_edge_lick(lick_num) = 1;
                    end
                end
            elseif class.dir == 'r'
                if class.reward
                    if class.type == 'i'
                        app.DLC.CLASS.is_r_reward_inner_tube_lick(lick_num) = 1;
                    elseif class.type == 'u'
                        app.DLC.CLASS.is_r_reward_under_tube_lick(lick_num) = 1;
                    elseif class.type == 'o'
                        app.DLC.CLASS.is_r_reward_outer_edge_lick(lick_num) = 1;
                    end
                    % add to r_reward_ind
                    app.DLC.CLASS.ind_lick_onset_r_reward = sort([app.DLC.CLASS.ind_lick_onset_r_reward; app.curr_inds_1K(1)]);
                    app.DLC.CLASS.ind_lick_offset_r_reward = sort([app.DLC.CLASS.ind_lick_onset_r_reward; app.curr_inds_1K(end)]);
                else
                    if class.type == 'i'
                        app.DLC.CLASS.is_r_noreward_inner_tube_lick(lick_num) = 1;
                    elseif class.type == 'u'
                        app.DLC.CLASS.is_r_noreward_under_tube_lick(lick_num) = 1;
                    elseif class.type == 'o'
                        app.DLC.CLASS.is_r_noreward_outer_edge_lick(lick_num) = 1;
                    end
                end
            end
        end

        function analyze(app)
            app.DLC_mat.data = app.points_qa;
            [app.DLC,app.EXPERIMENT_PARAMS] = ANALYZE(app.DLC_mat,app.EXPERIMENT_PARAMS, app.params, app.funcs);
            [app.DLC,app.EXPERIMENT_PARAMS] = redetect_harvest(app.DLC, app.EXPERIMENT_PARAMS, app.params, app.funcs, app.RECLASSIFIED);
            ind_lick_onset = app.DLC.IND.ind_lick_onset;
            app.ind_lick_onset_vid = [];
            for i = 1:length(ind_lick_onset)
                time = app.time_1K(ind_lick_onset(i));
                [~, ind_vid] = min(abs(app.time_vid - time));
                app.ind_lick_onset_vid = [app.ind_lick_onset_vid ind_vid];
            end
            ind_lick_offset = app.DLC.IND.ind_lick_offset;
            app.ind_lick_offset_vid = [];
            for i = 1:length(ind_lick_offset)
                time = app.time_1K(ind_lick_offset(i));
                [~, ind_vid] = min(abs(app.time_vid - time));
                app.ind_lick_offset_vid = [app.ind_lick_offset_vid ind_vid];
            end

            app.MaxLickLabel.Text = strcat("Total # of Licks: ", " ", num2str(app.DLC.IND.num_lick));
            lickNumChanged(app)
            plotEndPositionPlot(app)
            plotLickVelocityAndDistanceProfiles(app)
            plotSummary(app)
            editLickVelocityAndDistanceProfiles(app)
            editSummaryPlot(app)
        end
        
    end
    methods (Access = public)
        
        function app_params(app, EXPERIMENT_PARAMS, DLC, params, funcs)
            app.EXPERIMENT_PARAMS = EXPERIMENT_PARAMS;
            app.DLC_mat = DLC;
            app.points_backup = DLC.data;
            app.points_qa = DLC.data;
            app.funcs = funcs;
            % Set params
            app.params = PGH_params;

            % Set file name
            app.FileLabel.Text = app.EXPERIMENT_PARAMS.mat_FileName;

            % Set frame ratio
            data_FPS = table2array(app.DLC_mat.data);
            FPS = app.EXPERIMENT_PARAMS.FPS;
            time_vid = ((1/FPS) : (1/FPS) : size(data_FPS,1)/FPS)';
            time_1K = (time_vid(1) : 0.001 : time_vid(end))';
            app.time_vid = time_vid;
            app.time_1K = time_1K;

            % Load Video
            app.StatusLabel.Text = 'Loading Video...';
            fprintf('Loading Video... ')
            app.vidfile_name = [app.EXPERIMENT_PARAMS.mat_FileName(1:length(app.EXPERIMENT_PARAMS.mat_FileName)-4), '.mp4'];
            app.VidObj = VideoReader([app.EXPERIMENT_PARAMS.mat_PathName, app.vidfile_name]);
            app.StatusLabel.Text = '-';
            fprintf('--> Completed.')

            % Reset RECLASSIFIED
            app.RECLASSIFIED = [];
            app.RECLASSIFIED.class = [];
            app.RECLASSIFIED.ind_lick_onset = [];

            % Analyze
            app.StatusLabel.Text = 'Analyzing...';
            app.LickNumberField.Value = 1;
            analyze(app)
            app.StatusLabel.Text = 'Analysis Completed';
        end
        
        function closeWindow(app)
            delete *.fig
            app.delete
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.done = 0;
            app.analyze_done = true;
            app.qa_done = false;
            app.save_done = false;
            movegui(app.UIFigure,"center");
            set(app.GeoPlot, 'YDir','reverse');
            set(app.TracePlot, 'YDir','reverse');
            set(app.EndPositionPlot, 'YDir', 'reverse');
            app.VidFrame.Toolbar.Visible = 'off';
            disableDefaultInteractivity(app.VidFrame);
            rng(1)
        end

        % Callback function: ResetTool
        function ResetToolClicked(app, event)
            app.DLC_mat.data = app.points_backup;
            app.qa_done = false;
            app.save_done = false;
            app_params(app, app.EXPERIMENT_PARAMS, app.DLC_mat, app.params, app.funcs)
        end

        % Callback function: QATool
        function QAToolClicked(app, event)
            app.StatusLabel.Text = 'Performing QA...';
            app.DLC_mat.data = app.points_qa;
            [app.DLC_mat, app.EXPERIMENT_PARAMS] = QA(app.DLC_mat, app.EXPERIMENT_PARAMS);
            
            % Display frames in drop down menu
            [~, inds] = setdiff(app.DLC_mat.data, app.points_qa);
            updateDropDownFrames(app, inds);

            app.StatusLabel.Text = 'QA Completed';
            app.params.lick.qa = 1;
            app.qa_done = true;
            app.points_qa = app.DLC_mat.data;
            analyze(app)
        end

        % Callback function: AnalyzeTool
        function AnalyzeToolClicked(app, event)
            app.StatusLabel.Text = 'Analyzing...';
            app.LickNumberField.Value = 1;
            analyze(app)
            app.StatusLabel.Text = 'Analysis Completed';
            app.analyze_done = true;
        end

        % Callback function: SaveTool
        function SaveToolClicked(app, event)
            % use the most updated point_qa (will re-analyze if not done)
            if ~app.analyze_done
                app.StatusLabel.Text = 'Re-analyzing data...';
                analyze(app)
                app.StatusLabel.Text = 'Analysis Completed';
                app.analyze_done = true;
            end

            app.StatusLabel.Text = 'Building LICKS_ALL_DATA...';
            [app.LICKS_ALL_DATA, app.EXPERIMENT_PARAMS] = BUILD_LICKS_ALL_DATA(app.DLC, app.EXPERIMENT_PARAMS, app.params, app.funcs);
            app.StatusLabel.Text = 'LICKS_ALL_DATA built';

            app.StatusLabel.Text = 'Saving Lick Sorter...';
            fprintf('Saving Lick Sorter... ')
            app.hFig = PLOT_LICK_SORTER(app.LICKS_ALL_DATA, app.EXPERIMENT_PARAMS, app.params, app.funcs, 0);
            path = [app.DLC.FILE.path_to_analyzed '../../../analyzed_figs/behavior_data/tongue/'];
            saveas(app.hFig,[path app.EXPERIMENT_PARAMS.mat_FileName(1:end-4) '_lick_sorter'], 'pdf');
            saveas(app.hFig,[path app.EXPERIMENT_PARAMS.mat_FileName(1:end-4) '_lick_sorter'], 'png');
            savefig('temp.fig')
            close all
            app.StatusLabel.Text = 'Lick Sorter saved';
            fprintf('--> Completed.')

            if app.qa_done
                app.StatusLabel.Text = 'Saving _RECAL.mat...';
                fprintf('Saving _RECAL.mat... ')
                data = app.DLC.data;
                save([app.DLC.FILE.path_to_analyzed app.EXPERIMENT_PARAMS.mat_FileName(1:end-4) '_RECAL.mat'], 'data');
                app.StatusLabel.Text = '_RECAL.mat saved';
                fprintf('--> Completed.')
            end
            app.save_done = true;
        end

        % Callback function: PlotTool
        function PlotToolClicked(app, event)
            if ~app.save_done
                warndlg('Plot is not available before saving')
            else
                % display figure
                openfig('temp.fig', 'new', 'visible')
            end
        end

        % Callback function: CompletedTool
        function CompletedToolClicked(app, event)
            if ~app.save_done
                warndlg('Exit is not allowed before saving')
            else
                app.done = 1;
            end
        end

        % Button pushed function: LickMinus
        function LickMinusButtonPushed(app, event)
           if (app.LickNumberField.Value <= app.DLC.IND.num_lick && app.LickNumberField.Value > 1)
                app.LickNumberField.Value = app.LickNumberField.Value - 1;
                lickNumChanged(app)
            end
        end

        % Button pushed function: LickPlus
        function LickPlusButtonPushed(app, event)
            if (app.LickNumberField.Value < app.DLC.IND.num_lick && app.LickNumberField.Value > 0)
                app.LickNumberField.Value = app.LickNumberField.Value + 1;
                lickNumChanged(app)
            end
        end

        % Value changed function: LickNumberField
        function LickNumberFieldValueChanged(app, event)

            value = app.LickNumberField.Value;
            % make sure lick number is valid
            if (value <= app.DLC.IND.num_lick && value > 0)
                app.lickNumPrev = value;
                lickNumChanged(app)
            else
                if app.lickNumPrev == 1
                    app.LickNumberField.Value = 1;
                else
                    app.LickNumberField.Value = app.lickNumPrev;
                end
            end
        end

        % Button pushed function: FrameMinus
        function FrameMinusButtonPushed(app, event)
           if (app.FrameNumberField.Value <= length(app.curr_inds_1K) && app.FrameNumberField.Value > 1)
                app.FrameNumberField.Value = app.FrameNumberField.Value - 1;
                frameNumChanged(app)
            end
        end

        % Button pushed function: FramePlus
        function FramePlusButtonPushed(app, event)
            if (app.FrameNumberField.Value < length(app.curr_inds_1K) && app.FrameNumberField.Value > 0)
                app.FrameNumberField.Value = app.FrameNumberField.Value + 1;
                frameNumChanged(app)
            end
        end

        % Value changed function: FrameNumberField
        function FrameNumberFieldValueChanged(app, event)
            value = app.FrameNumberField.Value;
            % make sure frame number is valid
            if (value <= length(app.curr_inds_1K) && value > 0)
                app.frameNumPrev = value;
                frameNumChanged(app)
            else 
                app.FrameNumberField.Value = app.frameNumPrev;
            end
        end

        % Value changed function: QueryByFrameEditField
        function QueryByFrameEditFieldValueChanged(app, event)
            value = app.QueryByFrameEditField.Value;
            ind_lick_onset = app.ind_lick_onset_vid;
            ind_lick_offset = app.ind_lick_offset_vid;
            % see if frame is in any lick
            if find(ind_lick_onset>value, 1) - 1 ~= find(ind_lick_offset>value, 1)
                app.QueryByFrameWarningLabel.Text = "This frame is not in any lick";
                displayVidFrame(app, value);
            else
                app.QueryByFrameWarningLabel.Text = "";
                app.LickNumberField.Value = find(ind_lick_offset>value, 1);
                lickNumChanged(app)
                app.FrameNumberField.Value = value - ind_lick_onset(app.LickNumberField.Value) + 1;
                frameNumChanged(app)
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            if ~strcmp(app.DropDown.Value, app.DropDown.Items{1,1})
                app.QueryByFrameEditField.Value = str2double(app.DropDown.Value);
                QueryByFrameEditFieldValueChanged(app, event)
            end
        end

        % Button pushed function: ReclassifyButton
        function ReclassifyButtonPushed(app, event)
           new_class = setClassFromCheckBoxes(app);
           if ~isempty(new_class) 
                setDLCfromClass(app, new_class, app.LickNumberField.Value)
                app.RECLASSIFIED.ind_lick_onset = [app.RECLASSIFIED.ind_lick_onset; app.curr_inds_1K(1)];
                app.RECLASSIFIED.class = [app.RECLASSIFIED.class; new_class];
           else
               setCheckBoxesFromClass(app, app.curr_class)
           end
        end

        % Value changed function: ShowHarvestCheckBox
        function ShowHarvestCheckBoxValueChanged(app, event)
            plotSummary(app)
        end

        % Value changed function: ShowLegendCheckBox
        function ShowLegendCheckBoxValueChanged(app, event)
            if app.ShowLegendCheckBox.Value
                set(app.summaryLeg,'visible','on')
            else
                set(app.summaryLeg,'visible','off')
            end
        end

        % Value changed function: GroomingCheckBox
        function GroomingCheckBoxValueChanged(app, event)
            if app.GroomingCheckBox.Value
                v = 'on';
            else
                v = 'off';
            end
            for i = 1:length(app.ENDPOSITION.grooming)
                app.ENDPOSITION.grooming(i).Visible = v;
            end
        end

        % Value changed function: InnerCheckBox
        function InnerCheckBoxValueChanged(app, event)
            value = app.InnerCheckBox.Value;
            show_reward = value & app.RewardedCheckBox.Value;
            show_noreward = value & app.NotrewardedCheckBox.Value;
            if show_reward
                show_reward_v = 'on';
            else
                show_reward_v = 'off';
            end

            if show_noreward
                show_noreward_v = 'on';
            else
                show_noreward_v = 'off';
            end

            for i = 1:length(app.ENDPOSITION.r_reward_inner)
                app.ENDPOSITION.r_reward_inner(i).Visible = show_reward_v;
            end
            for i = 1:length(app.ENDPOSITION.r_noreward_inner)
                app.ENDPOSITION.r_noreward_inner(i).Visible = show_noreward_v;
            end
            for i = 1:length(app.ENDPOSITION.l_reward_inner)
                app.ENDPOSITION.l_reward_inner(i).Visible = show_reward_v;
            end
            for i = 1:length(app.ENDPOSITION.l_noreward_inner)
                app.ENDPOSITION.l_noreward_inner(i).Visible = show_noreward_v;
            end
        end

        % Value changed function: UnderCheckBox
        function UnderCheckBoxValueChanged(app, event)
            value = app.UnderCheckBox.Value;
            show_reward = value & app.RewardedCheckBox.Value;
            show_noreward = value & app.NotrewardedCheckBox.Value;
            if show_reward
                show_reward_v = 'on';
            else
                show_reward_v = 'off';
            end

            if show_noreward
                show_noreward_v = 'on';
            else
                show_noreward_v = 'off';
            end

            for i = 1:length(app.ENDPOSITION.r_reward_under)
                app.ENDPOSITION.r_reward_under(i).Visible = show_reward_v;
            end
            for i = 1:length(app.ENDPOSITION.r_noreward_under)
                app.ENDPOSITION.r_noreward_under(i).Visible = show_noreward_v;
            end
            for i = 1:length(app.ENDPOSITION.l_reward_under)
                app.ENDPOSITION.l_reward_under(i).Visible = show_reward_v;
            end
            for i = 1:length(app.ENDPOSITION.l_noreward_under)
                app.ENDPOSITION.l_noreward_under(i).Visible = show_noreward_v;
            end
        end

        % Value changed function: OuterCheckBox
        function OuterCheckBoxValueChanged(app, event)
            value = app.OuterCheckBox.Value;
            show_reward = value & app.RewardedCheckBox.Value;
            show_noreward = value & app.NotrewardedCheckBox.Value;
            if show_reward
                show_reward_v = 'on';
            else
                show_reward_v = 'off';
            end

            if show_noreward
                show_noreward_v = 'on';
            else
                show_noreward_v = 'off';
            end

            for i = 1:length(app.ENDPOSITION.r_reward_outer)
                app.ENDPOSITION.r_reward_outer(i).Visible = show_reward_v;
            end
            for i = 1:length(app.ENDPOSITION.r_noreward_outer)
                app.ENDPOSITION.r_noreward_outer(i).Visible = show_noreward_v;
            end
            for i = 1:length(app.ENDPOSITION.l_reward_outer)
                app.ENDPOSITION.l_reward_outer(i).Visible = show_reward_v;
            end
            for i = 1:length(app.ENDPOSITION.l_noreward_outer)
                app.ENDPOSITION.l_noreward_outer(i).Visible = show_noreward_v;
            end
        end

        % Value changed function: RewardedCheckBox
        function RewardedCheckBoxValueChanged(app, event)
            value = app.RewardedCheckBox.Value;
            show_inner = value & app.InnerCheckBox.Value;
            show_under = value & app.UnderCheckBox.Value;
            show_outer = value & app.OuterCheckBox.Value;
            if show_inner
                show_inner_v = 'on';
            else
                show_inner_v = 'off';
            end
            if show_under
                show_under_v = 'on';
            else
                show_under_v = 'off';
            end
            if show_outer
                show_outer_v = 'on';
            else
                show_outer_v = 'off';
            end

            for i = 1:length(app.ENDPOSITION.r_reward_inner)
                app.ENDPOSITION.r_reward_inner(i).Visible = show_inner_v;
            end
            for i = 1:length(app.ENDPOSITION.l_reward_inner)
                app.ENDPOSITION.l_reward_inner(i).Visible = show_inner_v;
            end
            for i = 1:length(app.ENDPOSITION.r_reward_under)
                app.ENDPOSITION.r_reward_under(i).Visible = show_under_v;
            end
            for i = 1:length(app.ENDPOSITION.l_reward_under)
                app.ENDPOSITION.l_reward_under(i).Visible = show_under_v;
            end
            for i = 1:length(app.ENDPOSITION.r_reward_outer)
                app.ENDPOSITION.r_reward_outer(i).Visible = show_outer_v;
            end
            for i = 1:length(app.ENDPOSITION.l_reward_outer)
                app.ENDPOSITION.l_reward_outer(i).Visible = show_outer_v;
            end
        end

        % Value changed function: NotrewardedCheckBox
        function NotrewardedCheckBoxValueChanged(app, event)
            value = app.NotrewardedCheckBox.Value;
            show_inner = value & app.InnerCheckBox.Value;
            show_under = value & app.UnderCheckBox.Value;
            show_outer = value & app.OuterCheckBox.Value;
            if show_inner
                show_inner_v = 'on';
            else
                show_inner_v = 'off';
            end
            if show_under
                show_under_v = 'on';
            else
                show_under_v = 'off';
            end
            if show_outer
                show_outer_v = 'on';
            else
                show_outer_v = 'off';
            end

            for i = 1:length(app.ENDPOSITION.r_noreward_inner)
                app.ENDPOSITION.r_noreward_inner(i).Visible = show_inner_v;
            end
            for i = 1:length(app.ENDPOSITION.l_noreward_inner)
                app.ENDPOSITION.l_noreward_inner(i).Visible = show_inner_v;
            end
            for i = 1:length(app.ENDPOSITION.r_noreward_under)
                app.ENDPOSITION.r_noreward_under(i).Visible = show_under_v;
            end
            for i = 1:length(app.ENDPOSITION.l_noreward_under)
                app.ENDPOSITION.l_noreward_under(i).Visible = show_under_v;
            end
            for i = 1:length(app.ENDPOSITION.r_noreward_outer)
                app.ENDPOSITION.r_noreward_outer(i).Visible = show_outer_v;
            end
            for i = 1:length(app.ENDPOSITION.l_noreward_outer)
                app.ENDPOSITION.l_noreward_outer(i).Visible = show_outer_v;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.ResetTool = uipushtool(app.Toolbar);
            app.ResetTool.ClickedCallback = createCallbackFcn(app, @ResetToolClicked, true);
            app.ResetTool.Icon = fullfile(pathToMLAPP, 'icons', 'reset-icon.png');
            app.QATool = uipushtool(app.Toolbar);
            app.QATool.ClickedCallback = createCallbackFcn(app, @QAToolClicked, true);
            app.QATool.Icon = fullfile(pathToMLAPP, 'icons', 'qa-icon.png');
            app.AnalyzeTool = uipushtool(app.Toolbar);
            app.AnalyzeTool.ClickedCallback = createCallbackFcn(app, @AnalyzeToolClicked, true);
            app.AnalyzeTool.Icon = fullfile(pathToMLAPP, 'icons', 'analyze-icon.png');
            app.SaveTool = uipushtool(app.Toolbar);
            app.SaveTool.ClickedCallback = createCallbackFcn(app, @SaveToolClicked, true);
            app.SaveTool.Icon = fullfile(pathToMLAPP, 'icons', 'save-icon.png');
            app.SaveTool.Separator = 'on';
            app.PlotTool = uipushtool(app.Toolbar);
            app.PlotTool.ClickedCallback = createCallbackFcn(app, @PlotToolClicked, true);
            app.PlotTool.Icon = fullfile(pathToMLAPP, 'icons', 'plot-icon.png');
            app.CompletedTool = uipushtool(app.Toolbar);
            app.CompletedTool.ClickedCallback = createCallbackFcn(app, @CompletedToolClicked, true);
            app.CompletedTool.Icon = fullfile(pathToMLAPP, 'icons', 'completed_icon.png');
            app.TracePlot = uiaxes(app.UIFigure);
            title(app.TracePlot, 'Trace')
            xlabel(app.TracePlot, 'x (mm)')
            ylabel(app.TracePlot, 'y (mm)')
            app.TracePlot.XLim = [-10 25];
            app.TracePlot.YLim = [-20 20];
            app.TracePlot.XTick = [-10 -5 0 5 10 15 20 25];
            app.TracePlot.XTickLabel = {'-10'; '-5'; '0'; '5'; '10'; '15'; '20'; '25'};
            app.TracePlot.YTick = [-20 -15 -10 -5 0 5 10 15 20];
            app.TracePlot.YTickLabel = {'-20'; '-15'; '-10'; '-5'; '0'; '5'; '10'; '15'; '20'};
            app.EndPositionPlot = uiaxes(app.UIFigure);
            title(app.EndPositionPlot, 'End positions (tip)')
            xlabel(app.EndPositionPlot, 'x position (mm)')
            ylabel(app.EndPositionPlot, 'y position (mm)')
            app.VelocityPlot = uiaxes(app.UIFigure);
            title(app.VelocityPlot, 'Velocity')
            xlabel(app.VelocityPlot, 'Time (ms)')
            ylabel(app.VelocityPlot, 'Velocity (mm/s)')
            app.VelocityPlot.XLim = [0 350];
            app.VelocityPlot.YLim = [-500 500];
            app.VelocityPlot.YTick = [-400 -200 0 200 400];
            app.PositionPlot = uiaxes(app.UIFigure);
            title(app.PositionPlot, 'Displacement')
            xlabel(app.PositionPlot, 'Time (ms)')
            ylabel(app.PositionPlot, 'Displacement (mm)')
            app.PositionPlot.XLim = [0 350];
            app.PositionPlot.YLim = [0 20];
            app.VidFrame = uiaxes(app.UIFigure);
            app.VidFrame.XLim = [-10 20];
            app.VidFrame.YLim = [-20 20];
            app.VidFrame.XTick = [];
            app.VidFrame.XTickLabel = '';
            app.VidFrame.YTick = [];
            app.VidFrame.YTickLabel = '';
            app.VidFrame.Visible = 'off';
            app.GeoPlot = uiaxes(app.UIFigure);
            title(app.GeoPlot, 'Geometrized tongue')
            xlabel(app.GeoPlot, 'x (mm)')
            ylabel(app.GeoPlot, 'y (mm)')
            app.GeoPlot.XLim = [-10 20];
            app.GeoPlot.YLim = [-20 20];
            app.GeoPlot.XTick = [-10 -5 0 5 10 15 20];
            app.GeoPlot.XTickLabel = {'-10'; '-5'; '0'; '5'; '10'; '15'; '20'};
            app.GeoPlot.YTick = [-20 -15 -10 -5 0 5 10 15 20];
            app.GeoPlot.YTickLabel = {'-20'; '-15'; '-10'; '-5'; '0'; '5'; '10'; '15'; '20'};
            app.SummaryPlot = uiaxes(app.UIFigure);
            title(app.SummaryPlot, 'Summary')
            xlabel(app.SummaryPlot, 'Time (s)')
            ylabel(app.SummaryPlot, 'Displacement (mm)')
            app.SummaryPlot.XLim = [0 350];
            app.SummaryPlot.YLim = [0 20];
            app.SummaryPlot.XTick = [0 50 100 150 200 250 300 350];
            app.SummaryPlot.YTick = [0 5 10 15 20 25];
            app.SummaryPlot.YTickLabel = {'0'; '5'; '10'; '15'; '20'; '25'};
            app.VideoFrameLabel = uilabel(app.UIFigure);
            app.VideoFrameLabel.Text = 'Video Frame';
            app.ReclassifyButton = uibutton(app.UIFigure, 'push');
            app.ReclassifyButton.ButtonPushedFcn = createCallbackFcn(app, @ReclassifyButtonPushed, true);
            app.ReclassifyButton.Text = 'Re-classify';
            app.TimeElapsedLabel = uilabel(app.UIFigure);
            app.TimeElapsedLabel.Text = 'Time Elapsed (ms): ';
            app.LickDurationLabel = uilabel(app.UIFigure);
            app.LickDurationLabel.Text = 'Lick Duration (ms): ';
            app.TipAngleLabel = uilabel(app.UIFigure);
            app.TipAngleLabel.Text = 'Tip Angle (°): ';
            app.EndPointAngleLabel = uilabel(app.UIFigure);
            app.EndPointAngleLabel.Text = 'End Point Angle (°): ';
            app.VelocitymmsLabel = uilabel(app.UIFigure);
            app.VelocitymmsLabel.Text = 'Velocity (mm/s): ';
            app.DisplacementmmLabel = uilabel(app.UIFigure);
            app.DisplacementmmLabel.Text = 'Displacement (mm): ';
            app.WithinlickframeLabel = uilabel(app.UIFigure);
            app.WithinlickframeLabel.Text = 'Within-lick frame #';
            app.MaxProtractionSpeedLabel = uilabel(app.UIFigure);
            app.MaxProtractionSpeedLabel.Text = 'Max Protraction Speed (mm/s): ';
            app.MaxDisplacementLabel = uilabel(app.UIFigure);
            app.MaxDisplacementLabel.Text = 'Max Displacement (mm): ';
            app.MaxFrameLabel = uilabel(app.UIFigure);
            app.MaxFrameLabel.Text = 'Total # of Frames:';
            app.FramePlus = uibutton(app.UIFigure, 'push');
            app.FramePlus.ButtonPushedFcn = createCallbackFcn(app, @FramePlusButtonPushed, true);
            app.FramePlus.Text = '+';
            app.FrameMinus = uibutton(app.UIFigure, 'push');
            app.FrameMinus.ButtonPushedFcn = createCallbackFcn(app, @FrameMinusButtonPushed, true);
            app.FrameMinus.Text = '-';
            app.FrameNumberField = uieditfield(app.UIFigure, 'numeric');
            app.FrameNumberField.ValueChangedFcn = createCallbackFcn(app, @FrameNumberFieldValueChanged, true);
            app.FrameNumberField.Value = 1;
            app.LickLabel = uilabel(app.UIFigure);
            app.LickLabel.Text = 'Lick #';
            app.LickPlus = uibutton(app.UIFigure, 'push');
            app.LickPlus.ButtonPushedFcn = createCallbackFcn(app, @LickPlusButtonPushed, true);
            app.LickPlus.Text = '+';
            app.LickMinus = uibutton(app.UIFigure, 'push');
            app.LickMinus.ButtonPushedFcn = createCallbackFcn(app, @LickMinusButtonPushed, true);
            app.LickMinus.Text = '-';
            app.LickNumberField = uieditfield(app.UIFigure, 'numeric');
            app.LickNumberField.ValueChangedFcn = createCallbackFcn(app, @LickNumberFieldValueChanged, true);
            app.LickNumberField.Value = 1;
            app.QueryByFrameLabel = uilabel(app.UIFigure);
            app.QueryByFrameLabel.Text = 'Frame #: ';
            app.QueryByFrameEditField = uieditfield(app.UIFigure, 'numeric');
            app.QueryByFrameEditField.ValueDisplayFormat = '%.0f';
            app.QueryByFrameEditField.ValueChangedFcn = createCallbackFcn(app, @QueryByFrameEditFieldValueChanged, true);
            app.MaxLickLabel = uilabel(app.UIFigure);
            app.MaxLickLabel.Text = 'Total # of Licks: ';
            app.LeftCheckBox = uicheckbox(app.UIFigure);
            app.LeftCheckBox.Text = 'Left';
            app.LickTypeLabel = uilabel(app.UIFigure);
            app.LickTypeLabel.Text = 'Lick Type: ';
            app.RightCheckBox = uicheckbox(app.UIFigure);
            app.RightCheckBox.Text = 'Right';
            app.RewardCheckBox = uicheckbox(app.UIFigure);
            app.RewardCheckBox.Text = 'Reward';
            app.NorewardCheckBox = uicheckbox(app.UIFigure);
            app.NorewardCheckBox.Text = 'No reward';
            app.InnertubeCheckBox = uicheckbox(app.UIFigure);
            app.InnertubeCheckBox.Text = 'Inner tube';
            app.OuteredgeCheckBox = uicheckbox(app.UIFigure);
            app.OuteredgeCheckBox.Text = 'Outer edge';
            app.UndertubeCheckBox = uicheckbox(app.UIFigure);
            app.UndertubeCheckBox.Text = 'Under tube';
            app.StatusTitleLabel = uilabel(app.UIFigure);
            app.StatusTitleLabel.Text = 'Status: ';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = '';
            app.FileLabel = uilabel(app.UIFigure);
            app.FileLabel.Text = '';
            app.FileTitleLabel = uilabel(app.UIFigure);
            app.FileTitleLabel.Text = 'File:';
            app.QueryByFrameWarningLabel = uilabel(app.UIFigure);
            app.QueryByFrameWarningLabel.Text = '';
            app.MaxRetractionSpeedLabel = uilabel(app.UIFigure);
            app.MaxRetractionSpeedLabel.Text = 'Max Retraction Speed (mm/s): ';
            app.FilterbyLickTypeLabel = uilabel(app.UIFigure);
            app.FilterbyLickTypeLabel.Text = 'Filter by Lick Type';
            app.InnerCheckBox = uicheckbox(app.UIFigure);
            app.InnerCheckBox.ValueChangedFcn = createCallbackFcn(app, @InnerCheckBoxValueChanged, true);
            app.InnerCheckBox.Text = 'Inner';
            app.InnerCheckBox.Value = true;
            app.UnderCheckBox = uicheckbox(app.UIFigure);
            app.UnderCheckBox.ValueChangedFcn = createCallbackFcn(app, @UnderCheckBoxValueChanged, true);
            app.UnderCheckBox.Text = 'Under';
            app.UnderCheckBox.Value = true;
            app.OuterCheckBox = uicheckbox(app.UIFigure);
            app.OuterCheckBox.ValueChangedFcn = createCallbackFcn(app, @OuterCheckBoxValueChanged, true);
            app.OuterCheckBox.Text = 'Outer';
            app.OuterCheckBox.Value = true;
            app.GroomingCheckBox = uicheckbox(app.UIFigure);
            app.GroomingCheckBox.ValueChangedFcn = createCallbackFcn(app, @GroomingCheckBoxValueChanged, true);
            app.GroomingCheckBox.Text = 'Grooming';
            app.GroomingCheckBox.Value = true;
            app.ShowHarvestCheckBox = uicheckbox(app.UIFigure);
            app.ShowHarvestCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowHarvestCheckBoxValueChanged, true);
            app.ShowHarvestCheckBox.Text = 'Show Harvest';
            app.NotrewardedCheckBox = uicheckbox(app.UIFigure);
            app.NotrewardedCheckBox.ValueChangedFcn = createCallbackFcn(app, @NotrewardedCheckBoxValueChanged, true);
            app.NotrewardedCheckBox.Text = 'Not rewarded';
            app.NotrewardedCheckBox.Value = true;
            app.RewardedCheckBox = uicheckbox(app.UIFigure);
            app.RewardedCheckBox.ValueChangedFcn = createCallbackFcn(app, @RewardedCheckBoxValueChanged, true);
            app.RewardedCheckBox.Text = 'Rewarded';
            app.RewardedCheckBox.Value = true;
            app.FilterbyRewardTypeLabel = uilabel(app.UIFigure);
            app.FilterbyRewardTypeLabel.Text = 'Filter by Reward Type';
            app.BrowseframesmodifiedbyQALabel = uilabel(app.UIFigure);
            app.BrowseframesmodifiedbyQALabel.Visible = 'off';
            app.BrowseframesmodifiedbyQALabel.Text = 'Browse frames modified by QA';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'----------------'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Visible = 'off';
            app.DropDown.Value = '----------------';
            app.ShowLegendCheckBox = uicheckbox(app.UIFigure);
            app.ShowLegendCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowLegendCheckBoxValueChanged, true);
            app.ShowLegendCheckBox.Text = 'Show Legend';
            app.ShowLegendCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VL_LickSort

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end